classdef SignatureProject_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text2         matlab.ui.control.Label
        panel         matlab.ui.container.Panel
        pushbutton11  matlab.ui.control.Button
        pushbutton3   matlab.ui.control.Button
        pushbutton2   matlab.ui.control.Button
        pushbutton1   matlab.ui.control.Button
        axes2         matlab.ui.control.UIAxes
        axes1         matlab.ui.control.UIAxes
        panel2        matlab.ui.container.Panel
        uipanel8      matlab.ui.container.Panel
        pushbutton10  matlab.ui.control.Button
        resultview    matlab.ui.control.Label
        text3         matlab.ui.control.Label
        axes8         matlab.ui.control.UIAxes
        axes7         matlab.ui.control.UIAxes
        axes5         matlab.ui.control.UIAxes
        axes6         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SignatureProject_OpeningFcn(app, varargin)
            % --- Executes just before SignatureProject is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SignatureProject (see VARARGIN)
            
            % Choose default command line output for SignatureProject
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % --- Executes on button press in pushbutton10.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.panel, 'visible','on')
            set(handles.panel2, 'visible','off')
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % --- Executes on button press in pushbutton11.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            cla (handles.axes2,'reset');
            cla (handles.axes7,'reset');
            cla (handles.axes8,'reset');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [a b] = uigetfile('*.*','All Files');
            img1=imread([b a]);
            handles.img1 = img1;
            guidata(hObject, handles);
            imshow(img1,'Parent',handles.axes1);
            imshow(img1,'Parent',handles.axes7);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [a b] = uigetfile('*.*','All Files');
            img2=imread([b a]);
            handles.img2 = img2;
            guidata(hObject, handles);
            imshow(img2,'Parent',handles.axes2);
            imshow(img2,'Parent',handles.axes8);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.panel, 'visible','off')
            if isfield(handles, 'img1') && isfield(handles, 'img2')
                [V1,imgp1] = SignatureProjectFuntion(handles.img1);
                imshow(imgp1,'Parent',handles.axes5);
                [V2,imgp2] = SignatureProjectFuntion(handles.img2);
                imshow(imgp2,'Parent',handles.axes6);
                flag = 1;
                for i=1:9
                    if abs(V2(i)-V1(i)) > i
                        flag = 0;
                        break;
                    end
                end
                if flag
                    msgbox('Valid of Signature Verification');
                    set(handles.resultview,'string','Valid of Signature Verification');
                else
                    warndlg('Invalid of Signature Verification');
                    set(handles.resultview,'string','Invalid Signature');
                end
            else
                errordlg('Please Goto Back Secrean And Select both images');
                set(handles.resultview,'string','Please Goto Back Secrean And Select both images');
            end
            set(handles.panel2, 'visible','on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SignatureProject';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.panel2 = uipanel(app.uipanel1);
            app.panel2.Tag = 'panel2';
            app.axes6 = uiaxes(app.panel2);
            app.axes6.Tag = 'axes6';
            app.axes5 = uiaxes(app.panel2);
            app.axes5.Tag = 'axes5';
            app.axes7 = uiaxes(app.panel2);
            app.axes7.Tag = 'axes7';
            app.axes8 = uiaxes(app.panel2);
            app.axes8.Tag = 'axes8';
            app.uipanel8 = uipanel(app.panel2);
            app.uipanel8.Title = ' Recognised Result';
            app.uipanel8.Tag = 'uipanel8';
            app.text3 = uilabel(app.uipanel8);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Status :';
            app.resultview = uilabel(app.uipanel8);
            app.resultview.Tag = 'resultview';
            app.resultview.Text = '';
            app.pushbutton10 = uibutton(app.uipanel8, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = 'Main Screen';
            app.panel = uipanel(app.uipanel1);
            app.panel.Tag = 'panel';
            app.axes1 = uiaxes(app.panel);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.panel);
            app.axes2.Tag = 'axes2';
            app.pushbutton1 = uibutton(app.panel, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Select First Sign. Image';
            app.pushbutton2 = uibutton(app.panel, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Select Second Sign. Image';
            app.pushbutton3 = uibutton(app.panel, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Result';
            app.pushbutton11 = uibutton(app.panel, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'Clear Image';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Signature Verification';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignatureProject_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SignatureProject_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end